classdef HandGApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MelodicfingersLabel  matlab.ui.control.Label
        InstructionsButton   matlab.ui.control.Button
        ExitButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        CameraonButton       matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExitButton
        function QuitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
                        
            
            app.InstructionsButton.Enable = 'off';
            
            app.Instructions = Instructions;
            app.InstructionsButton.Enable = 'On';
          
            
        end

        % Callback function
        function countFingers(app, event)
      %      clear;
%clc;
%cam = webcam('Microsoft® LifeCam Cinema(TM)');
%preview(cam);

%videoframe = snapshot(cam);
%frameSize = size(videoframe);
%videoplayer = vision.VideoPlayer('Position',[100 100 [frameSize(2), frameSize(1)]]);

        end

        % Button pushed function: CameraonButton
        function onClick(app, event)
             vid = videoinput('winvideo',2);
            % Create blank image
            hImage = image(app.UIAxes,zeros(720,1280,3)); %If image resolution is 1280x720
            % pause(1)
            % These lines set proper aspect ratio
            app.UIAxes.XLim = [0,1280];
            app.UIAxes.YLim = [0,720];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[1280,720,1])
            % Preview the image
            preview(vid,hImage)
            run("countFing.m");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MelodicfingersLabel = uilabel(app.UIFigure);
            app.MelodicfingersLabel.Text = 'Melodic fingers';
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CameraonButton = uibutton(app.UIFigure, 'push');
            app.CameraonButton.ButtonPushedFcn = createCallbackFcn(app, @onClick, true);
            app.CameraonButton.Text = 'Camera on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HandGApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end